classdef synchronous_monolayer_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        DimensionEditFieldLabel         matlab.ui.control.Label
        DimensionEditField              matlab.ui.control.NumericEditField
        SeedsEditFieldLabel             matlab.ui.control.Label
        SeedsEditField                  matlab.ui.control.EditField
        SeedEg2131rowcolumnSyntaxmustbeexactornocellswillbeseededLabel  matlab.ui.control.Label
        InitializeButton                matlab.ui.control.Button
        StepButton                      matlab.ui.control.Button
        FastButton                      matlab.ui.control.Button
        SlowButton                      matlab.ui.control.Button
        StopButton                      matlab.ui.control.Button
        SynchronousMonolayerModelLabel  matlab.ui.control.Label
        textOut                         matlab.ui.control.Label
        P2EditFieldLabel                matlab.ui.control.Label
        P2EditField                     matlab.ui.control.EditField
        HorizonalVerticalGrowthRateLabel  matlab.ui.control.Label
        DiagonalGrowthRateLabel         matlab.ui.control.Label
        P1EditFieldLabel                matlab.ui.control.Label
        P1EditField                     matlab.ui.control.EditField
        displayCount                    matlab.ui.control.Label
    end


    properties (Access = private)     
        p1;
        p2;  
        app_seed_matrix;
        app_p_array;
        app_parsed_seeds;
        app_cell_matrix;
        slowFlag;
        fastFlag;
        stepCount;
    end

    methods (Access = private)
    
        function app_cell_matrix = app_buildMatrix(app,val)
            app_cell_matrix = buildMatrix(val);
            
        end
        
        function app_parsed_seeds = app_seedParser(app, val)
            app_parsed_seeds = seedParser(val);
        end 
    
        function [app_seed_matrix, app_p_array] = app_seedFunc(app, toSeed, type, numSeeds, seedArray)
           [seed_matrix, p_array] = seedFunc(toSeed, type, numSeeds, seedArray);
           app_seed_matrix = seed_matrix;
           app_p_array = p_array;
        end
    
        function [app_p_array, app_cell_matrix] = app_proliferate(app, p, p1, p_array, seed)
            [p_array, cell_matrix] = proliferate(p, p1, p_array, seed); 
           app_p_array = p_array;
           app_cell_matrix = cell_matrix;
        end
       
    end


    methods (Access = private)

        % Value changed function: DimensionEditField
        function DimensionEditFieldValueChanged(app, event)

        end

        % Button pushed function: InitializeButton
        function InitializeButtonPushed(app, event)
            app.fastFlag = 0;
            app.slowFlag = 0;
            value = app.DimensionEditField.Value;
            seeds = app.SeedsEditField.Value;
            app.stepCount = 0;
            app.displayCount.Text = num2str(app.stepCount);
            %app.p1 = app.P1EditField.Value;
            %app.p2 = app.P2EditField.Value;    
              
            %Creating Necessary Variables
            app_cell_matrix = app_buildMatrix(app,value);
            app_parsed_seeds = app_seedParser(app, seeds);
            app_p_array = 0;
            app_seed_matrix = 0;
            
            if(size(app_parsed_seeds,1) < 1)
                   
            else 
                [app_seed_matrix, app_p_array] = app_seedFunc(app,app_cell_matrix,'s',size(app_parsed_seeds,1), app_parsed_seeds);
                app_cell_matrix = app_seed_matrix;
            end
            
            %Setting required global variables
            app.app_p_array = app_p_array;
            app.app_cell_matrix = app_seed_matrix;

            %Displaying
            image(app.UIAxes, app_cell_matrix);
            colormap(app.UIAxes,'gray');


            %end
        end

        % Button pushed function: StepButton
        function StepButtonPushed(app, event)
            
            %Calling required values
            p = str2double(app.P1EditField.Value);
            p1 = str2double(app.P2EditField.Value);
           
            p_array = app.app_p_array;
            seed = app.app_cell_matrix;
            
            %run proliferation
            [app_p_array, app_cell_matrix] = app_proliferate(app, p, p1, p_array, seed);
            
            %set proper global variables
            app.app_p_array = app_p_array;
            app.app_cell_matrix = app_cell_matrix;
            
            %set Display
            if size(app_p_array,1) ~= 0
                app.stepCount = app.stepCount + 1;
                app.displayCount.Text = num2str(app.stepCount);
            end
            
            %Displaying
            image(app.UIAxes, app_cell_matrix);
            colormap(app.UIAxes,'gray');
            
            
        end

        % Button pushed function: SlowButton
        function SlowButtonPushed(app, event)
            
            app.slowFlag = 1;
            app.fastFlag = 0;
            
            while app.slowFlag == 1
                app.stepCount = app.stepCount + 1;
                app.displayCount.Text = num2str(app.stepCount);
                %Calling required values
                p = str2double(app.P1EditField.Value);
                p1 = str2double(app.P2EditField.Value);
                p_array = app.app_p_array;
                seed = app.app_cell_matrix;
            
                %run proliferation
                [app_p_array, app_cell_matrix] = app_proliferate(app, p, p1, p_array, seed);
            
                %set proper global variables
                app.app_p_array = app_p_array;
                app.app_cell_matrix = app_cell_matrix;
                
                %Stop
                if size(app_p_array,1) == 0;
                   app.slowFlag = 0; 
                end
            
                %Displaying
                image(app.UIAxes, app_cell_matrix);
                colormap(app.UIAxes,'gray');
                pause(0.5);
            
            end


        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.slowFlag = 0;
            app.fastFlag = 0;
        end

        % Button pushed function: FastButton
        function FastButtonPushed(app, event)
            app.slowFlag = 0;
            app.fastFlag = 1;
            
            while app.fastFlag == 1
                app.stepCount = app.stepCount + 1;
                app.displayCount.Text = num2str(app.stepCount);
                %Calling required values
                p = str2double(app.P1EditField.Value);
                p1 = str2double(app.P2EditField.Value);
                p_array = app.app_p_array;
                seed = app.app_cell_matrix;
            
                %run proliferation
                [app_p_array, app_cell_matrix] = app_proliferate(app, p, p1, p_array, seed);
            
                %set proper global variables
                app.app_p_array = app_p_array;
                app.app_cell_matrix = app_cell_matrix;
                
                %Stop
                if size(app_p_array,1) == 0;
                   app.fastFlag = 0; 
                end
            
                %Displaying
                image(app.UIAxes, app_cell_matrix);
                colormap(app.UIAxes,'gray');
                pause(0.125);
            
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.UIAxes.ZTick = [];
            app.DimensionEditFieldLabel = uilabel(app.UIFigure);
            app.DimensionEditFieldLabel.Text = 'Dimension';
            app.DimensionEditField = uieditfield(app.UIFigure, 'numeric');
            app.DimensionEditField.ValueChangedFcn = createCallbackFcn(app, @DimensionEditFieldValueChanged, true);
            app.SeedsEditFieldLabel = uilabel(app.UIFigure);
            app.SeedsEditFieldLabel.Text = 'Seeds';
            app.SeedsEditField = uieditfield(app.UIFigure, 'text');
            app.SeedEg2131rowcolumnSyntaxmustbeexactornocellswillbeseededLabel = uilabel(app.UIFigure);
            app.SeedEg2131rowcolumnSyntaxmustbeexactornocellswillbeseededLabel.Text = 'Seed Eg: [2,1 ; 3,1 ; row,column]      Syntax must be exact or no cells will be seeded';
            app.InitializeButton = uibutton(app.UIFigure, 'push');
            app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeButtonPushed, true);
            app.InitializeButton.Text = 'Initialize ';
            app.StepButton = uibutton(app.UIFigure, 'push');
            app.StepButton.ButtonPushedFcn = createCallbackFcn(app, @StepButtonPushed, true);
            app.StepButton.Text = 'Step';
            app.FastButton = uibutton(app.UIFigure, 'push');
            app.FastButton.ButtonPushedFcn = createCallbackFcn(app, @FastButtonPushed, true);
            app.FastButton.Text = 'Fast';
            app.SlowButton = uibutton(app.UIFigure, 'push');
            app.SlowButton.ButtonPushedFcn = createCallbackFcn(app, @SlowButtonPushed, true);
            app.SlowButton.Text = 'Slow';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.SynchronousMonolayerModelLabel = uilabel(app.UIFigure);
            app.SynchronousMonolayerModelLabel.Text = 'Synchronous Monolayer Model ';
            app.textOut = uilabel(app.UIFigure);
            app.textOut.Text = 'Step: ';
            app.P2EditFieldLabel = uilabel(app.UIFigure);
            app.P2EditFieldLabel.Text = 'P2';
            app.P2EditField = uieditfield(app.UIFigure, 'text');
            app.P2EditField.Value = '0';
            app.HorizonalVerticalGrowthRateLabel = uilabel(app.UIFigure);
            app.HorizonalVerticalGrowthRateLabel.Text = 'Horizonal/Vertical Growth Rate';
            app.DiagonalGrowthRateLabel = uilabel(app.UIFigure);
            app.DiagonalGrowthRateLabel.Text = 'Diagonal Growth Rate';
            app.P1EditFieldLabel = uilabel(app.UIFigure);
            app.P1EditFieldLabel.Text = 'P1';
            app.P1EditField = uieditfield(app.UIFigure, 'text');
            app.P1EditField.Value = '0';
            app.displayCount = uilabel(app.UIFigure);
            app.displayCount.Text = '0';
        end
    end

    methods (Access = public)

        % Construct app
        function app = synchronous_monolayer_app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end